classdef MemristorRawDataDealWith < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OpenDataMenu            matlab.ui.container.Menu
        SaveDataMenu            matlab.ui.container.Menu
        ExportDataMenu          matlab.ui.container.Menu
        SelectFolderMenu        matlab.ui.container.Menu
        SWEEPMenu               matlab.ui.container.Menu
        IVMenu                  matlab.ui.container.Menu
        DiscontinuityMenu       matlab.ui.container.Menu
        PULSEMenu               matlab.ui.container.Menu
        TIMEMenu                matlab.ui.container.Menu
        CurrentRevise           matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
        DataEditing             matlab.ui.container.Panel
        DataFilesEditField      matlab.ui.control.EditField
        DataFilesLabel          matlab.ui.control.Label
        DiscontinuityEditField  matlab.ui.control.NumericEditField
        UIDiscontinuityLabel    matlab.ui.control.Label
        DeleteButton            matlab.ui.control.Button
        AddButton               matlab.ui.control.Button
        ModifyButton            matlab.ui.control.Button
        DrawingControl          matlab.ui.container.Panel
        TypeDropDown            matlab.ui.control.DropDown
        TypeLabel               matlab.ui.control.Label
        ProcessDropDown         matlab.ui.control.DropDown
        ProcessLabel            matlab.ui.control.Label
        DeleteCheckBox          matlab.ui.control.CheckBox
        StartSpinner            matlab.ui.control.Spinner
        StartLabel              matlab.ui.control.Label
        StopSpinner             matlab.ui.control.Spinner
        StopLabel               matlab.ui.control.Label
        SingleDataCheckBox      matlab.ui.control.CheckBox
        DoubleDataCheckBox      matlab.ui.control.CheckBox
        DestinationFolderLabel  matlab.ui.control.Label
        ExportDataFolderLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        UIAxesContextMenu       matlab.ui.container.ContextMenu
        YMenu                   matlab.ui.container.Menu
        LogMenu                 matlab.ui.container.Menu
        LinearMenu              matlab.ui.container.Menu
        SETMenu                 matlab.ui.container.Menu
        RESETMenu               matlab.ui.container.Menu
    end


    properties (Access = private)
        Data
        DrawingNumber
        DrawingRange
        ExportDataFolder
        LineProperties
        YAxisType % Description
    end

    methods (Access = private)

        function status = FunOpenData(app)
            if exist([app.DestinationFolderLabel.Text,'\Data.mat'],'file')
                try
                    load([app.DestinationFolderLabel.Text,'\Data.mat'],'Data');
                    if isfield(Data,'type')
                        cache = num2cell(categorical({Data.type}));
                        [Data.Type] = cache{:};
                        Data = rmfield(Data,'type');
                        cache = num2cell(categorical({Data.Process}));
                        [Data.Process] = cache{:};
                        app.SaveDataMenu.Enable = 'on';
                    end
                    app.Data = Data; %#ok<*ADPROP>
                    status.status = true;
                catch
                    status.message = '存在旧版本 Data.mat 文件或文件以损坏';
                    status.status = false;
                end
            else
                try
                    app.Data = dir([app.DestinationFolderLabel.Text,'\*.csv']);
                    app.Data = rmfield(app.Data,["date","isdir","datenum"]);
                    for ii = 1:length(app.Data)
                        if contains(app.Data(ii).name,"-PSET")
                            app.Data(ii).Type = 'PULSE';
                            app.Data(ii).Process = 'SET';
                        elseif contains(app.Data(ii).name,"-PRESET")
                            app.Data(ii).Type = 'PULSE';
                            app.Data(ii).Process = 'RESET';
                        elseif contains(app.Data(ii).name,"-SET")
                            app.Data(ii).Type = 'SWEEP';
                            app.Data(ii).Process = 'SET';
                        elseif contains(app.Data(ii).name,"-RESET")
                            app.Data(ii).Type = 'SWEEP';
                            app.Data(ii).Process = 'RESET';
                        else
                            app.Data(ii).Type = 'TIME';
                            app.Data(ii).Process = 'TIME';
                        end
                        app.Data(ii).date = datetime(app.Data(ii).name(1:15),'InputFormat','yyyyMMdd-HHmmss');
                        cache = load([app.Data(ii).folder,'\',app.Data(ii).name]);
                        app.Data(ii).Time = cache(:,1);
                        app.Data(ii).Voltage = cache(:,2);
                        app.Data(ii).Current = cache(:,3);
                        if contains(app.Data(ii).Type,"SWEEP")
                            [~,app.Data(ii).Discontinuity] = max(abs(diff(cache(:,3))));
                        else
                            app.Data(ii).Discontinuity = 1;
                        end
                        app.Data(ii).Delete = false;
                    end
                    cache = num2cell(categorical({app.Data.Type}));
                    [app.Data.Type] = cache{:};
                    cache = num2cell(categorical({app.Data.Process}));
                    [app.Data.Process] = cache{:};
                    if isempty(app.Data)
                        status.message = '所选文件夹不包含兼容数据';
                        status.status = false;
                    else
                        status.status = true;
                        app.SaveDataMenu.Enable = 'on';
                    end
                catch
                    status.message = '数据读取存在错误';
                    status.status = false;
                end
            end
            FunDrawing(app)
        end

        function FunDrawing(app)
            app.UIAxes.NextPlot = 'add';
            DrawingNumber = find([app.Data.Type] == 'SWEEP'); % 创建 SWEEP 数据 IV Line 属性
            app.LineProperties.SWEEP = DrawingNumber;
            maxlength = max(arrayfun(@(x) length(x.Voltage),app.Data(DrawingNumber)));
            Data.Voltage = arrayfun(@(x) [x.Voltage;nan(maxlength-length(x.Voltage),1)],app.Data(DrawingNumber),'UniformOutput',false);
            Data.Voltage = [Data.Voltage{:}];
            Data.Current = arrayfun(@(x) [x.Current;nan(maxlength-length(x.Current),1)],app.Data(DrawingNumber),'UniformOutput',false);
            Data.Current = [Data.Current{:}] + app.CurrentRevise.Value;
            app.LineProperties.VCabs = gobjects(length(DrawingNumber),1);
            app.LineProperties.VCabs = plot(app.UIAxes,Data.Voltage,abs(Data.Current),'Color','#D0D0D0');
            set(app.LineProperties.VCabs,{'Visible'},{'off'});
            app.LineProperties.VCnoabs = gobjects(length(DrawingNumber),1);
            app.LineProperties.VCnoabs = plot(app.UIAxes,Data.Voltage,Data.Current,'Color','#D0D0D0');
            set(app.LineProperties.VCnoabs,{'Visible'},{'off'});
            Data.Voltage = arrayfun(@(x) [x.Voltage(x.Discontinuity);NaN],app.Data(DrawingNumber),'UniformOutput',false);
            Data.Voltage = [Data.Voltage{:}];
            Data.Current = arrayfun(@(x) [x.Current(x.Discontinuity);NaN],app.Data(DrawingNumber),'UniformOutput',false);
            Data.Current = [Data.Current{:}] + app.CurrentRevise.Value;
            app.LineProperties.Discontinuityabs = gobjects(length(DrawingNumber),1);
            app.LineProperties.Discontinuityabs = plot(app.UIAxes,Data.Voltage,abs(Data.Current),'LineStyle','none','Marker','o','MarkerEdgeColor','r');
            set(app.LineProperties.Discontinuityabs,{'Visible'},{'off'});
            app.LineProperties.Discontinuity = gobjects(length(DrawingNumber),1);
            app.LineProperties.Discontinuity = plot(app.UIAxes,Data.Voltage,Data.Current,'LineStyle','none','Marker','o','MarkerEdgeColor','r');
            set(app.LineProperties.Discontinuity,{'Visible'},{'off'});
            DrawingNumber = find([app.Data.Type] ~= 'SWEEP'); % PULSE TIME
            app.LineProperties.unSWEEP = DrawingNumber;
            app.LineProperties.TV= arrayfun(@(x) plot(app.UIAxes,x.Time,x.Voltage,'k'),app.Data(DrawingNumber));
            set(app.LineProperties.TV,{'Visible'},{'off'});
            yyaxis(app.UIAxes,'right')
            app.LineProperties.TCabs = arrayfun(@(x) plot(app.UIAxes,x.Time,abs(x.Current + app.CurrentRevise.Value),'r'),app.Data(DrawingNumber));
            set(app.LineProperties.TCabs,{'Visible'},{'off'});
            app.LineProperties.TCnoabs = arrayfun(@(x) plot(app.UIAxes,x.Voltage,x.Current + app.CurrentRevise.Value,'r'),app.Data(DrawingNumber));
            set(app.LineProperties.TCnoabs,{'Visible'},{'off'});
            yyaxis(app.UIAxes,'left')
        end

        function FunSaveData(app)
            Data = app.Data;
            save([app.DestinationFolderLabel.Text,'\Data.mat'],'Data')
            app.SaveDataMenu.Enable = 'off';
        end

        function FunEditLineObjects(app)
            app.SWEEPMenu.Visible = 'off';
            app.PULSEMenu.Visible = 'off';
            app.TIMEMenu.Visible = 'off';
            FunDrawingParameterCheck(app)
            Color = {'#0072BD','#D95319','#EDB120','#7E2F8E','#77AC30','#4DBEEE','#A2142F'};
            if ~isempty(app.DrawingNumber)
                if length(app.DrawingNumber) == 1
                    Index = app.LineProperties.SWEEP(app.DrawingNumber(1));
                    app.DataFilesEditField.Value = app.Data(Index).name;
                    app.DiscontinuityEditField.Value = (app.Data(Index).Discontinuity - 1) * app.Data(Index).Voltage(2);
                else
                    app.DataFilesEditField.Value = '';
                    app.DiscontinuityEditField.Value = 0;
                end
                switch app.TypeDropDown.Value
                    case 'SWEEP'
                        app.SWEEPMenu.Visible = app.ExportDataFolder;
                        switch app.YAxisType
                            case 'Log'
                                app.UIAxes.YScale = "Log";
                                set(app.LineProperties.VCabs,{'Visible'},{'off'})
                                set(app.LineProperties.VCabs(app.DrawingNumber),{'Visible','Color'},{'on',Color{1}})
                            case 'Linear'
                                app.UIAxes.YScale = "Linear";
                                set(app.LineProperties.VCnoabs,{'Visible'},{'off'})
                                set(app.LineProperties.VCnoabs(app.DrawingNumber),{'Visible','Color'},{'on',Color{1}})
                        end
                    case 'PULSE'
                        app.PULSEMenu.Visible = app.ExportDataFolder;
                        timestop = 0;
                        for ii = app.DrawingNumber
                            %                             FunDrawingXaxisIsTime(app,app.Data(ii).Time + timestop,app.Data(ii).Voltage,app.Data(ii).Current)
                            timestop = timestop + app.Data(ii).Time(end) + mean(diff(app.Data(ii).Time));
                            if ii == app.DrawingNumber(1)
                                hold(app.UIAxes,'on')
                            end
                        end
                        hold(app.UIAxes,'off')
                    case 'TIME'
                        app.TIMEMenu.Visible = app.ExportDataFolder;
                        ii = app.DrawingNumber;
                        switch app.YAxisType
                            case 'Log'
                                %                                 semilogy(app.UIAxes,app.Data(ii).Voltage,abs(app.Data(ii).Current),'-')
                            case 'Linear'
                                %                                 plot(app.UIAxes,app.Data(ii).Voltage,app.Data(ii).Current,'-')
                        end
                    case 'Delete'
                        ii = app.DrawingNumber;
                        switch app.Data(ii).Type
                            case 'SWEEP'
                                switch app.YAxisType
                                    case 'Log'
                                        %                                         semilogy(app.UIAxes,app.Data(ii).Voltage,abs(app.Data(ii).Current),'-')
                                    case 'Linear'
                                        %                                         plot(app.UIAxes,app.Data(ii).Voltage,app.Data(ii).Current,'-')
                                end
                            otherwise
                                %                                 FunDrawingXaxisIsTime(app,app.Data(ii).Time,app.Data(ii).Voltage,app.Data(ii).Current)
                        end
                end
            else
                app.DataFilesEditField.Value = '';
                app.DiscontinuityEditField.Value = 0;
            end
        end

        function FunDrawingParameterCheck(app)
            jj = length(app.DrawingRange);
            if jj > 0
                %                 if app.SingleDataCheckBox.Value
                %                     app.StopSpinner.Value = app.StartSpinner.Value;
                %                 elseif app.DoubleDataCheckBox.Value
                %                     app.StopSpinner.Value = app.StartSpinner.Value + 1;
                %                 end
                app.DrawingNumber = app.DrawingRange(app.StartSpinner.Value:app.StopSpinner.Value);
            end
        end

        function FunUpdateDrawingRange(app)
            if contains(app.TypeDropDown.Value,'SWEEP')
                logicalProcess = [app.Data(app.LineProperties.SWEEP).Process] == app.ProcessDropDown.Value ...
                    | contains(app.ProcessDropDown.Value,'ALL');
                app.DrawingRange = find([app.Data(app.LineProperties.SWEEP).Delete] == app.DeleteCheckBox.Value & logicalProcess);
            else
                cache = find([app.Data.Type] == app.TypeDropDown.Value);
                cache = cache([app.Data(cache).Delete] == app.DeleteCheckBox.Value);
                app.DrawingRange = find(ismember(app.LineProperties.unSWEEP,cache));
            end
            if length(app.DrawingRange) > 1
                limits = [1 length(app.DrawingRange)];
                app.StartSpinner.Enable = 'on';
            else
                app.StartSpinner.Enable = 'off';
                app.StopSpinner.Enable = 'off';
                limits = [1 2];
            end
            app.StartSpinner.Limits = limits;
            app.StopSpinner.Limits = limits;
        end

        function Data = FunDataInterp(app, Index, Process)
            Voltage = max(abs(arrayfun(@(S) S.Voltage(round(end/2)),app.Data(Index))));
            Voltage_Change = min(abs(arrayfun(@(x) mean(abs(diff(x.Voltage))),app.Data(Index))));
            Voltage = [0:Voltage_Change:Voltage (Voltage - Voltage_Change):-Voltage_Change:0]' * Process;
            I1 = arrayfun(@(S) interp1(S.Voltage(1:round(end/2)),S.Current(1:round(end/2)), ...
                Voltage(1:round(end/2))),app.Data(Index),'UniformOutput',false);
            I2 = arrayfun(@(S) interp1(S.Voltage(round(end/2)+1:end),S.Current(round(end/2)+1:end), ...
                Voltage(round(end/2)+1:end)),app.Data(Index),'UniformOutput',false);
            Data = [Voltage [I1{:}; I2{:}]];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenDataMenu
        function OpenDataMenuMenuSelected(app, event)
            foldername = uigetdir('C:\Users\Public\Documents\');
            if foldername ~= 0
                app.DestinationFolderLabel.Text = foldername;
                status = FunOpenData(app);
                if status.status
                    app.UIAxes.Visible = 'on';
                    app.ExportDataMenu.Enable = 'on';
                    app.DataEditing.Visible = 'on';
                    app.DrawingControl.Visible = 'on';
                    app.ExportDataFolder = 'off';
                    app.YAxisType = 'Log';
                    FunUpdateDrawingRange(app)
                    FunEditLineObjects(app)
                else
                    uiconfirm(app.UIFigure,status.message,'请检查数据','Icon','warning','Options',{'OK'})
                end
            end
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuMenuSelected(app, event)
            FunSaveData(app)
        end

        % Menu selected function: SelectFolderMenu
        function SelectFolderMenuMenuSelected(app, event)
            foldername = uigetdir('C:\Users\Public\Documents\');
            if foldername ~= 0
                app.ExportDataFolderLabel.Text = foldername;
                app.ExportDataFolder = 'on';
                FunEditLineObjects(app)
            end
        end

        % Menu selected function: IVMenu
        function IVMenuMenuSelected(app, event)
            SET = app.DrawingNumber(find(categorical({app.Data(app.DrawingNumber).Process}) == "SET"));
            RESET = app.DrawingNumber(find(categorical({app.Data(app.DrawingNumber).Process}) == "RESET"));
            if length(SET) == length(RESET)
                SET = FunDataInterp(app,SET,1);
                RESET = FunDataInterp(app,RESET,-1);
                cache = [SET; RESET];
                Options = uiconfirm(app.UIFigure,'对所有电流值取绝对值？','确认','Options',{'Yes','No'});
                if categorical({Options}) == "Yes"
                    cache(:,2:end) = abs(cache(:,2:end));
                end
                writematrix(cache,[app.ExportDataFolderLabel.Text,'\IVSweep.csv'])
            else
                uiconfirm(app.UIFigure,'SET RESET 数据数量不同！','请检查数据','Icon','warning','Options',{'OK'})
            end
        end

        % Menu selected function: DiscontinuityMenu
        function DiscontinuityMenuMenuSelected(app, event)
            cache = ([app.Data(app.DrawingNumber).Discontinuity] - 1)' .* ...
                arrayfun(@(x) mean(abs(diff(x.Voltage))),app.Data(app.DrawingNumber));
            writematrix(cache,[app.ExportDataFolderLabel.Text,'\Discontinuity.csv'])
        end

        % Menu selected function: PULSEMenu
        function PULSEMenuSelected(app, event)

        end

        % Menu selected function: TIMEMenu
        function TIMEMenuSelected(app, event)

        end

        % Value changed function: DeleteCheckBox, ProcessDropDown, 
        % TypeDropDown
        function FilterChanged(app, event)
            if app.DoubleDataCheckBox.Value
                if contains(app.ProcessDropDown.Value,["SET","RESET"])
                    app.ProcessDropDown.Value = 'ALL';
                end
            end
            if contains(app.TypeDropDown.Value,["TIME","Delete"])
                app.DoubleDataCheckBox.Value = false;
                app.SingleDataCheckBox.Value = true;
                if contains(app.ProcessDropDown.Value,["SET","RESET"])
                    app.ProcessDropDown.Value = 'ALL';
                end
            end
            %                     app.StartLabel.Text = 'High';
            %                     app.StopLabel.Text = 'Low';
            %             app.UIAxes.NextPlot = 'replaceall';
            FunUpdateDrawingRange(app)
            FunEditLineObjects(app)
        end

        % Menu selected function: LinearMenu, LogMenu
        function YAxisTypeDropDownValueChanged(app, event)
            app.YAxisType = event.Source.Text;
            FunEditLineObjects(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonButtonPushed(app, event)
            if length(app.DrawingNumber) == 1
                app.Data(app.DrawingNumber).Delete = true;
                app.SaveDataMenu.Enable = 'on';
                FunEditLineObjects(app)
            end
        end

        % Button pushed function: AddButton
        function AddButtonButtonPushed(app, event)
            if length(app.DrawingNumber) == 1
                app.Data(app.DrawingNumber).Delete = false;
                app.SaveDataMenu.Enable = 'on';
                FunEditLineObjects(app)
            end
        end

        % Button pushed function: ModifyButton
        function ModifyButtonButtonPushed(app, event)
            if length(app.DrawingNumber) == 1
                app.Data(app.DrawingNumber).Discontinuity = round(app.DiscontinuityEditField.Value / app.Data(app.DrawingNumber).Voltage(2)) + 1;
                app.SaveDataMenu.Enable = 'on';
                FunEditLineObjects(app)
            end
        end

        % Value changed function: StartSpinner
        function StartSpinnerValueChanged(app, event)
            if app.StartSpinner.Value > app.StopSpinner.Value || app.SingleDataCheckBox.Value
                app.StopSpinner.Value = app.StartSpinner.Value;
            end
            if app.DoubleDataCheckBox.Value
                app.StopSpinner.Value = app.StartSpinner.Value + 1;
            end
            FunEditLineObjects(app)
        end

        % Value changed function: StopSpinner
        function StopSpinnerValueChanged(app, event)
            if app.StartSpinner.Value > app.StopSpinner.Value
                app.StartSpinner.Value = app.StopSpinner.Value;
            end
            FunEditLineObjects(app)
        end

        % Value changed function: SingleDataCheckBox
        function SingleDataCheckBoxValueChanged(app, event)
            if ~contains(app.TypeDropDown.Value,["TIME","Delete"])
                if app.SingleDataCheckBox.Value
                    app.StopSpinner.Enable = 'off';
                    app.StartSpinner.Step = 1;
                    app.DoubleDataCheckBox.Value = false;
                    app.StopSpinner.Value = app.StartSpinner.Value;
                else
                    app.StopSpinner.Enable = 'on';
                end
                FunEditLineObjects(app)
            else
                app.SingleDataCheckBox.Value = true;
            end
        end

        % Value changed function: DoubleDataCheckBox
        function DoubleDataCheckBoxValueChanged(app, event)
            if  contains(app.TypeDropDown.Value,["SWEEP","PULSE"]) && (app.Data(app.DrawingNumber(1)).Process == "SET")
                if app.DoubleDataCheckBox.Value
                    app.StopSpinner.Enable = 'off';
                    app.StartSpinner.Step = 2;
                    app.SingleDataCheckBox.Value = false;
                    FunEditLineObjects(app)
                else
                    app.StopSpinner.Enable = 'on';
                    app.StartSpinner.Step = 1;
                end
            else
                app.DoubleDataCheckBox.Value = false;
            end
        end

        % Context menu opening function: UIAxesContextMenu
        function UIAxesContextMenuOpening(app, event)
            app.SETMenu.Text = num2str(sum(([app.Data(app.DrawingNumber).Process] == "SET")));
            app.RESETMenu.Text = num2str(sum(([app.Data(app.DrawingNumber).Process] == "RESET")));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if categorical(app.SaveDataMenu.Enable) == "true"
                FunSaveData(app)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memristor Raw Data Deal With';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenDataMenu = uimenu(app.UIFigure);
            app.OpenDataMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDataMenuMenuSelected, true);
            app.OpenDataMenu.Text = '打开数据';
            app.SaveDataMenu = uimenu(app.UIFigure);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuMenuSelected, true);
            app.SaveDataMenu.Enable = 'off';
            app.SaveDataMenu.Text = '保存数据';
            app.ExportDataMenu = uimenu(app.UIFigure);
            app.ExportDataMenu.Enable = 'off';
            app.ExportDataMenu.Text = '导出数据';
            app.SelectFolderMenu = uimenu(app.ExportDataMenu);
            app.SelectFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectFolderMenuMenuSelected, true);
            app.SelectFolderMenu.Text = '选择导出文件夹';
            app.SWEEPMenu = uimenu(app.ExportDataMenu);
            app.SWEEPMenu.Visible = 'off';
            app.SWEEPMenu.Text = 'SWEEP';
            app.IVMenu = uimenu(app.SWEEPMenu);
            app.IVMenu.MenuSelectedFcn = createCallbackFcn(app, @IVMenuMenuSelected, true);
            app.IVMenu.Text = '电压电流';
            app.DiscontinuityMenu = uimenu(app.SWEEPMenu);
            app.DiscontinuityMenu.MenuSelectedFcn = createCallbackFcn(app, @DiscontinuityMenuMenuSelected, true);
            app.DiscontinuityMenu.Text = '突变电压';
            app.PULSEMenu = uimenu(app.ExportDataMenu);
            app.PULSEMenu.MenuSelectedFcn = createCallbackFcn(app, @PULSEMenuSelected, true);
            app.PULSEMenu.Visible = 'off';
            app.PULSEMenu.Text = 'PULSE';
            app.TIMEMenu = uimenu(app.ExportDataMenu);
            app.TIMEMenu.MenuSelectedFcn = createCallbackFcn(app, @TIMEMenuSelected, true);
            app.TIMEMenu.Visible = 'off';
            app.TIMEMenu.Text = 'TIME';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'V')
            ylabel(app.UIAxes, 'I')
            app.UIAxes.Visible = 'off';
            app.ExportDataFolderLabel = uilabel(app.UIFigure);
            app.ExportDataFolderLabel.Text = '导出数据文件夹';
            app.DestinationFolderLabel = uilabel(app.UIFigure);
            app.DestinationFolderLabel.Text = '目标文件夹';
            app.DrawingControl = uipanel(app.UIFigure);
            app.DrawingControl.Title = '绘图控制';
            app.DrawingControl.Visible = 'off';
            app.DoubleDataCheckBox = uicheckbox(app.DrawingControl);
            app.DoubleDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoubleDataCheckBoxValueChanged, true);
            app.DoubleDataCheckBox.Text = '双数据';
            app.SingleDataCheckBox = uicheckbox(app.DrawingControl);
            app.SingleDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleDataCheckBoxValueChanged, true);
            app.SingleDataCheckBox.Text = '单数据';
            app.SingleDataCheckBox.Value = true;
            app.StopLabel = uilabel(app.DrawingControl);
            app.StopLabel.Text = 'Stop';
            app.StopSpinner = uispinner(app.DrawingControl);
            app.StopSpinner.Limits = [1 Inf];
            app.StopSpinner.ValueChangedFcn = createCallbackFcn(app, @StopSpinnerValueChanged, true);
            app.StopSpinner.Enable = 'off';
            app.StopSpinner.Value = 1;
            app.StartLabel = uilabel(app.DrawingControl);
            app.StartLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.DrawingControl);
            app.StartSpinner.Limits = [1 Inf];
            app.StartSpinner.ValueChangedFcn = createCallbackFcn(app, @StartSpinnerValueChanged, true);
            app.StartSpinner.Value = 1;
            app.DeleteCheckBox = uicheckbox(app.DrawingControl);
            app.DeleteCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterChanged, true);
            app.DeleteCheckBox.Text = 'Delete';
            app.ProcessLabel = uilabel(app.DrawingControl);
            app.ProcessLabel.Text = '过程';
            app.ProcessDropDown = uidropdown(app.DrawingControl);
            app.ProcessDropDown.Items = {'ALL', 'SET', 'RESET'};
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterChanged, true);
            app.ProcessDropDown.Value = 'ALL';
            app.TypeLabel = uilabel(app.DrawingControl);
            app.TypeLabel.Text = '数据类型';
            app.TypeDropDown = uidropdown(app.DrawingControl);
            app.TypeDropDown.Items = {'SWEEP', 'PULSE', 'TIME'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterChanged, true);
            app.TypeDropDown.Value = 'SWEEP';
            app.DataEditing = uipanel(app.UIFigure);
            app.DataEditing.Title = '数据编辑';
            app.DataEditing.Visible = 'off';
            app.ModifyButton = uibutton(app.DataEditing, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonButtonPushed, true);
            app.ModifyButton.Text = '更新突变电压';
            app.AddButton = uibutton(app.DataEditing, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.DataEditing, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UIDiscontinuityLabel = uilabel(app.DataEditing);
            app.UIDiscontinuityLabel.Text = '突变电压';
            app.DiscontinuityEditField = uieditfield(app.DataEditing, 'numeric');
            app.DataFilesLabel = uilabel(app.DataEditing);
            app.DataFilesLabel.Text = '数据文件';
            app.DataFilesEditField = uieditfield(app.DataEditing, 'text');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.CurrentRevise = uieditfield(app.UIFigure, 'numeric');
            app.UIAxesContextMenu = uicontextmenu(app.UIFigure);
            app.UIAxesContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @UIAxesContextMenuOpening, true);
            app.YMenu = uimenu(app.UIAxesContextMenu);
            app.YMenu.Text = 'Y轴刻度模式';
            app.LogMenu = uimenu(app.YMenu);
            app.LogMenu.MenuSelectedFcn = createCallbackFcn(app, @YAxisTypeDropDownValueChanged, true);
            app.LogMenu.Text = 'Log';
            app.LinearMenu = uimenu(app.YMenu);
            app.LinearMenu.MenuSelectedFcn = createCallbackFcn(app, @YAxisTypeDropDownValueChanged, true);
            app.LinearMenu.Text = 'Linear';
            app.SETMenu = uimenu(app.UIAxesContextMenu);
            app.RESETMenu = uimenu(app.UIAxesContextMenu);
            % Assign app.UIAxesContextMenu
            app.UIAxes.ContextMenu = app.UIAxesContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemristorRawDataDealWith

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end