classdef Memristor_Raw_Data_Deal_With < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Max_amount_of_data    matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        AmountOfFile          matlab.ui.control.NumericEditField
        AmountOfFileLabel     matlab.ui.control.Label
        ExportDataListBox     matlab.ui.control.ListBox
        ExportData            matlab.ui.control.Button
        DoubleData            matlab.ui.control.CheckBox
        UIDiscontinuity       matlab.ui.control.NumericEditField
        UIDiscontinuityLabel  matlab.ui.control.Label
        Add                   matlab.ui.control.Button
        Delete                matlab.ui.control.Button
        Modify                matlab.ui.control.Button
        DataFiles             matlab.ui.control.EditField
        DataFilesLabel        matlab.ui.control.Label
        FolderName            matlab.ui.control.EditField
        FolderNameLabel       matlab.ui.control.Label
        SingleData            matlab.ui.control.CheckBox
        Stop                  matlab.ui.control.Spinner
        StopLabel             matlab.ui.control.Label
        YAxisType             matlab.ui.control.DropDown
        YAxisTypeLabel        matlab.ui.control.Label
        Start                 matlab.ui.control.Spinner
        StartLabel            matlab.ui.control.Label
        Process               matlab.ui.control.DropDown
        ProcessLabel          matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        app_Filename
        app_Current_raw
        app_Voltage_raw
        app_Time_raw
        app_Discontinuity
        app_Drawing_number
    end

    methods (Access = private)

        function app_Start(app)
            if exist([app.FolderName.Value '\Data.mat'], 'file')
                load([app.FolderName.Value '\Data.mat'], '*')
                app.app_Filename = Filename;
                app.app_Current_raw = Current_raw;
                app.app_Voltage_raw = Voltage_raw;
                app.app_Time_raw = Time_raw;
                app.app_Discontinuity = Discontinuity;
            else
                app.app_Filename = ls([app.FolderName.Value '\*.csv']);
                cacle = inputdlg('单个数据最大数量','Input');
                app.Max_amount_of_data.Value = str2double(cacle{1});
                [app.app_Current_raw, app.app_Voltage_raw, app.app_Time_raw, app.app_Discontinuity] = app_raed_raw(app);
            end
        end

        function app_Stop(app)
            Filename = app.app_Filename;
            Current_raw = app.app_Current_raw;
            Voltage_raw = app.app_Voltage_raw;
            Time_raw = app.app_Time_raw;
            Discontinuity = app.app_Discontinuity;
            save([app.FolderName.Value '\Data.mat'], ...
                'Filename', 'Current_raw', 'Voltage_raw', 'Time_raw', 'Discontinuity')
        end

        function [Current_raw, Voltage_raw, Time_raw, Discontinuity] = app_raed_raw(app)
            Amount_of_file = size(app.app_Filename, 1);
            Time_raw = zeros(app.Max_amount_of_data.Value, Amount_of_file);
            Voltage_raw = zeros(app.Max_amount_of_data.Value, Amount_of_file);
            Current_raw = zeros(app.Max_amount_of_data.Value, Amount_of_file);
            for ii = 1:Amount_of_file
                [Time, Voltage, Current] = app_importfile(app, [app.FolderName.Value '\' app.app_Filename(ii, :)]);
                Data_length = length(Time);
                half_Data_length = round(Data_length/2);
                Data_length = Data_length + 1;
                Time_raw(:, ii) = [Time(1:half_Data_length) ; ...
                    Inf(app.Max_amount_of_data.Value - Data_length, 1) ; ...
                    Time(half_Data_length:end)];
                Voltage_raw(:, ii) = [Voltage(1:half_Data_length) ; ...
                    Inf(app.Max_amount_of_data.Value - Data_length, 1) ; ...
                    Voltage(half_Data_length:end)];
                Current_raw(:, ii) = [Current(1:half_Data_length) ; ...
                    Inf(app.Max_amount_of_data.Value - Data_length, 1) ; ...
                    Current(half_Data_length:end)];
            end
            cache = Current_raw;
            for ii =1:Amount_of_file
                inf_index = find(cache(:, ii) == inf);
                cache(inf_index, ii) = cache(inf_index(1) - 1, ii);
            end
            cache = abs(diff(cache));
            [~, Discontinuity] = max(cache);
        end

        function [Time, Voltage, Current] = app_importfile(~, filename)
            opts = delimitedTextImportOptions("NumVariables", 3);
            opts.DataLines = [1, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["Time", "Voltage", "Current"];
            opts.VariableTypes = ["double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            tbl = readtable(filename, opts);
            Time = tbl.Time;
            Voltage = tbl.Voltage;
            Current = tbl.Current;
        end

        function app_Drawing(app)
            app_Drawing_parameter_check(app)
            if ~isempty(app.app_Drawing_number)
                if length(app.app_Drawing_number) == 1
                    Cache = strrep(app.app_Filename(app.app_Drawing_number(1), :), 'set', 'SET');
                    Cache = strrep(Cache, '_', '-');
                    app.DataFiles.Value = Cache;
                    app.UIDiscontinuity.Value = (app.app_Discontinuity(app.app_Drawing_number(1)) - 1) * app.app_Voltage_raw(2, app.app_Drawing_number(1));
                else
                    app.DataFiles.Value = '';
                    app.UIDiscontinuity.Value = 0;
                end
            else
                app.DataFiles.Value = '';
                app.UIDiscontinuity.Value = 0;
            end
            switch app.YAxisType.Value
                case 'Log'
                    semilogy(app.UIAxes, 0, 0)
                case 'Linear'
                    plot(app.UIAxes, 0, 0)
            end
            hold(app.UIAxes, 'on')
            for ii = app.app_Drawing_number
                plot(app.UIAxes, app.app_Voltage_raw(:, ii), abs(app.app_Current_raw(:, ii)), 'k')
            end
            for ii = app.app_Drawing_number
                plot(app.UIAxes, app.app_Voltage_raw(app.app_Discontinuity(ii), ii), abs(app.app_Current_raw(app.app_Discontinuity(ii), ii)), 'or')
            end
            hold(app.UIAxes, 'off')
        end

        function app_Drawing_parameter_check(app)
            Amount_of_file = size(app.app_Filename, 1);
            switch app.Process.Value
                case 'ALL'
                    process = '2';
                case 'Delete'
                    process = 'set';
                case 'Normal'
                    process = '-';
                otherwise
                    process = ['-' app.Process.Value];
            end
            app.app_Drawing_number = zeros(1, Amount_of_file);
            jj = 0;
            for ii = 1:Amount_of_file
                if contains(app.app_Filename(ii, :), process)
                    jj = jj + 1;
                    app.app_Drawing_number(jj) = ii;
                end
            end
            app.AmountOfFile.Value = jj;
            app.app_Drawing_number = app.app_Drawing_number(1:jj);
            if jj > 0
                if app.SingleData.Value
                    if app.Start.Value > jj
                        app.Start.Value = jj;
                    end
                    app.Stop.Value = app.Start.Value;
                    app.app_Drawing_number = app.app_Drawing_number(app.Start.Value);
                elseif app.DoubleData.Value
                    if app.Start.Value + 1 > jj
                        app.Start.Value = app.Start.Value - 2;
                    end
                    app.Stop.Value = app.Start.Value + 1;
                    app.app_Drawing_number = app.app_Drawing_number(app.Start.Value:app.Stop.Value);
                else
                    if app.Start.Value > jj
                        app.Start.Value = jj;
                    end
                    if app.Stop.Value > jj
                        app.Stop.Value = jj;
                    end
                    app.app_Drawing_number = app.app_Drawing_number(app.Start.Value:app.Stop.Value);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            foldername = uigetdir('C:\Users\Public\Documents\');
            if foldername == 0
                delete(app)
            else
                app.FolderName.Value = foldername;
                app_Start(app)
                app_Drawing(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app_Stop(app)
            delete(app)
        end

        % Value changed function: Process
        function ProcessValueChanged(app, event)
            if app.DoubleData.Value
                if contains(app.Process.Value, ["SET", "RESET", "Delete"])
                    app.Process.Value = 'Normal';
                end
            end
            app_Drawing(app)
        end

        % Value changed function: YAxisType
        function YAxisTypeValueChanged(app, event)
            cla(app.UIAxes,'reset')
            app_Drawing(app)
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            if length(app.app_Drawing_number) == 1
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :), 'SET', 'set');
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :), '-', '_');
            end
            app_Drawing(app)
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            if length(app.app_Drawing_number) == 1
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :),'set', 'SET');
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :), '_', '-');
            end
            app_Drawing(app)
        end

        % Button pushed function: Modify
        function ModifyButtonPushed(app, event)
            if length(app.app_Drawing_number) == 1
                app.app_Discontinuity(app.app_Drawing_number(1)) = round(app.UIDiscontinuity.Value / app.app_Voltage_raw(2, app.app_Drawing_number(1))) + 1;
            end
            app_Drawing(app)
        end

        % Value changed function: SingleData
        function SingleDataValueChanged(app, event)
            if app.SingleData.Value
                app.Start.Step = 1;
                app.DoubleData.Value = false;
                app.Stop.Value = app.Start.Value;
            end
            app_Drawing(app)
        end

        % Value changed function: DoubleData
        function DoubleDataValueChanged(app, event)
            if  contains(app.Process.Value, ["Normal", "ALL"])
                if app.DoubleData.Value && contains(app.app_Filename(app.app_Drawing_number(1), :), ["-SET", "_set"])
                    app.Start.Step = 2;
                    app.SingleData.Value = false;
                else
                    app.Start.Step = 1;
                    app.DoubleData.Value = false;
                end
            else
                app.DoubleData.Value = false;
            end
            app_Drawing(app)
        end

        % Value changed function: Start
        function StartValueChanged(app, event)
            if app.Start.Value > app.Stop.Value
                app.Stop.Value = app.Start.Value;
            end
            app_Drawing(app)
        end

        % Value changed function: Stop
        function StopValueChanged(app, event)
            if app.Start.Value > app.Stop.Value
                app.Start.Value = app.Stop.Value;
            end
            app_Drawing(app)
        end

        % Button pushed function: ExportData
        function ExportDataButtonPushed(app, event)
            folderName = uigetdir('C:\Users\Public\Documents\');
            for ii = 1:length(app.ExportDataListBox.Value)
                switch app.ExportDataListBox.Value{ii}
                    case 'app.app_Filename'
                        index = '(app.app_Drawing_number, :)';
                    case 'app.app_Discontinuity'
                        index = '(app.app_Drawing_number) .* app.app_Voltage_raw(2, app.app_Drawing_number) - app.app_Voltage_raw(2, app.app_Drawing_number)';
                    otherwise
                        index = '(:, app.app_Drawing_number)';
                end
                eval(['writematrix(' app.ExportDataListBox.Value{ii} index ', ''' folderName '\' app.ExportDataListBox.Value{ii}(9:end) '.csv' '''' ')'])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memristor Raw Data Deal With';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'V')
            ylabel(app.UIAxes, 'I')
            app.ProcessLabel = uilabel(app.UIFigure);
            app.ProcessLabel.Text = '过程';
            app.Process = uidropdown(app.UIFigure);
            app.Process.Items = {'Normal', 'SET', 'RESET', 'Delete', 'ALL'};
            app.Process.ValueChangedFcn = createCallbackFcn(app, @ProcessValueChanged, true);
            app.Process.Value = 'Normal';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.Start = uispinner(app.UIFigure);
            app.Start.Limits = [1 Inf];
            app.Start.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.Start.Value = 1;
            app.YAxisTypeLabel = uilabel(app.UIFigure);
            app.YAxisTypeLabel.Text = 'Y轴刻度模式';
            app.YAxisType = uidropdown(app.UIFigure);
            app.YAxisType.Items = {'Log', 'Linear'};
            app.YAxisType.ValueChangedFcn = createCallbackFcn(app, @YAxisTypeValueChanged, true);
            app.YAxisType.Value = 'Log';
            app.StopLabel = uilabel(app.UIFigure);
            app.StopLabel.Text = 'Stop';
            app.Stop = uispinner(app.UIFigure);
            app.Stop.Limits = [1 Inf];
            app.Stop.ValueChangedFcn = createCallbackFcn(app, @StopValueChanged, true);
            app.Stop.Value = 1;
            app.SingleData = uicheckbox(app.UIFigure);
            app.SingleData.ValueChangedFcn = createCallbackFcn(app, @SingleDataValueChanged, true);
            app.SingleData.Text = '单数据';
            app.SingleData.Value = true;
            app.FolderNameLabel = uilabel(app.UIFigure);
            app.FolderNameLabel.Text = '目标文件夹';
            app.FolderName = uieditfield(app.UIFigure, 'text');
            app.DataFilesLabel = uilabel(app.UIFigure);
            app.DataFilesLabel.Text = '数据文件';
            app.DataFiles = uieditfield(app.UIFigure, 'text');
            app.Modify = uibutton(app.UIFigure, 'push');
            app.Modify.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.Modify.Text = '更新突变电压';
            app.Delete = uibutton(app.UIFigure, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete';
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add';
            app.UIDiscontinuityLabel = uilabel(app.UIFigure);
            app.UIDiscontinuityLabel.Text = '突变电压';
            app.UIDiscontinuity = uieditfield(app.UIFigure, 'numeric');
            app.DoubleData = uicheckbox(app.UIFigure);
            app.DoubleData.ValueChangedFcn = createCallbackFcn(app, @DoubleDataValueChanged, true);
            app.DoubleData.Text = '双数据';
            app.ExportData = uibutton(app.UIFigure, 'push');
            app.ExportData.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportData.Text = '导出数据';
            app.ExportDataListBox = uilistbox(app.UIFigure);
            app.ExportDataListBox.Items = {'时间', '电压', '电流', '对应文件名', '突变电压'};
            app.ExportDataListBox.ItemsData = {'app.app_Time_raw', 'app.app_Voltage_raw', 'app.app_Current_raw', 'app.app_Filename', 'app.app_Discontinuity'};
            app.ExportDataListBox.Multiselect = 'on';
            app.ExportDataListBox.Value = {'app.app_Current_raw', 'app.app_Discontinuity'};
            app.AmountOfFileLabel = uilabel(app.UIFigure);
            app.AmountOfFileLabel.Text = '数据总量';
            app.AmountOfFile = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '单个数据最大数量';
            app.Max_amount_of_data = uieditfield(app.UIFigure, 'numeric');
            app.Max_amount_of_data.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Memristor_Raw_Data_Deal_With

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end